classdef FashionImageApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        LoadButton             matlab.ui.control.Button
        DropDown               matlab.ui.control.DropDown
        PredefinedImagesLabel  matlab.ui.control.Label
        Brightness             matlab.ui.control.Label
        Blurry                 matlab.ui.control.Label
        ImageAxes              matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function updateimage(app,imagefile)
             
            try
                im = imread(imagefile);
            catch ME
                If problem reading image, display error message
                uialert(app.UIFigure, ME.message, 'Image Error');
                return;
            end

            
            % Create histograms based on number of color channels
            switch size(im,3)
                case 1
                    % Display the grayscale image
                    imagesc(app.ImageAxes,im);
                case 3
                    % Display the truecolor image
                    imagesc(app.ImageAxes,im);
                    
                    [brightness, message] = isBright(imagefile);
                    app.Brightness.Text=message;
                    [var, message] = isBlurry(imagefile, 21);
                    app.Blurry.Text=message;
                otherwise
                    % Error when image is not grayscale or truecolor
                    uialert(app.UIFigure, 'Image must be grayscale or truecolor.', 'Image Error');
                    return;
            end         
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Configure image axes
            app.ImageAxes.Visible = 'off';
            app.ImageAxes.Colormap = gray(256);
            axis(app.ImageAxes, 'image');
            
            % Update the image
            updateimage(app, 'sweat.jpg');
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            
            % Update the image
            updateimage(app, app.DropDown.Value);
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
               
            % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [f, p] = uigetfile(filterspec);
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
               fname = [p f];
               updateimage(app, fname);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Fashion App';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load Clothing Image';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'Sweat', 'Blurry', 'Over Exposed', 'Dark'};
            app.DropDown.ItemsData = {'sweat.jpg', 'blur.jpg', 'overExp.jpg', 'dark.jpg'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'sweat.jpg';
            app.PredefinedImagesLabel = uilabel(app.UIFigure);
            app.PredefinedImagesLabel.Text = 'Predefined Images';
            app.Brightness = uilabel(app.UIFigure);
            app.Brightness.Text = '';
            app.Blurry = uilabel(app.UIFigure);
            app.Blurry.Text = '';
            app.ImageAxes = uiaxes(app.UIFigure);
            app.ImageAxes.XTick = [];
            app.ImageAxes.XTickLabel = {'[ ]'};
            app.ImageAxes.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FashionImageApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end